classdef GrayScottController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GrayScottContollerUIFigure     matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadmodelMenu                  matlab.ui.container.Menu
        SavemodelMenu                  matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        ModelSettingsMenu              matlab.ui.container.Menu
        ColormapsMenu                  matlab.ui.container.Menu
        JetPearsonpaperMenu            matlab.ui.container.Menu
        ParulaMatlabdefaultMenu        matlab.ui.container.Menu
        CoolMenu                       matlab.ui.container.Menu
        PresetsMenu                    matlab.ui.container.Menu
        Labyrinth1Menu                 matlab.ui.container.Menu
        Labyrinth2Menu                 matlab.ui.container.Menu
        Chaos1Menu                     matlab.ui.container.Menu
        Chaos2Menu                     matlab.ui.container.Menu
        SpotsMenu                      matlab.ui.container.Menu
        MitosisMenu                    matlab.ui.container.Menu
        SolitonesMenu                  matlab.ui.container.Menu
        FlowerMenu                     matlab.ui.container.Menu
        FingerMenu                     matlab.ui.container.Menu
        USkateMenu                     matlab.ui.container.Menu
        MazesMenu                      matlab.ui.container.Menu
        SpiralsMenu                    matlab.ui.container.Menu
        DefaultMenu                    matlab.ui.container.Menu
        TimetorunMenu                  matlab.ui.container.Menu
        InfdefaultMenu                 matlab.ui.container.Menu
        Menu20000                      matlab.ui.container.Menu
        Menu50000                      matlab.ui.container.Menu
        Menu100000                     matlab.ui.container.Menu
        AppSettingsMenu                matlab.ui.container.Menu
        ColorschemeMenu                matlab.ui.container.Menu
        MATLABdefaultMenu              matlab.ui.container.Menu
        DarkMenu                       matlab.ui.container.Menu
        LightMenu                      matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        ManualMenu                     matlab.ui.container.Menu
        AboutPPPMenu                   matlab.ui.container.Menu
        AbouttheAppMenu                matlab.ui.container.Menu
        Controller10Panel              matlab.ui.container.Panel
        RestartButton                  matlab.ui.control.Button
        ParametersPanel                matlab.ui.container.Panel
        D_vEditField                   matlab.ui.control.NumericEditField
        D_vLabel                       matlab.ui.control.Label
        D_uEditField                   matlab.ui.control.NumericEditField
        D_uLabel                       matlab.ui.control.Label
        FSlider                        matlab.ui.control.Slider
        FSliderLabel                   matlab.ui.control.Label
        kSlider                        matlab.ui.control.Slider
        kSliderLabel                   matlab.ui.control.Label
        EquationsPanel                 matlab.ui.container.Panel
        dotVD_vnabla2VUV2FkVLabel      matlab.ui.control.Label
        dotUD_unabla2UUV2F1ULabel      matlab.ui.control.Label
        VideoFormatDropDown            matlab.ui.control.DropDown
        VideoformatDropDownLabel       matlab.ui.control.Label
        VideoFPSEditField              matlab.ui.control.NumericEditField
        VideoFPSEditFieldLabel         matlab.ui.control.Label
        PlotFieldDropDown              matlab.ui.control.DropDown
        FieldLabel                     matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        BoundaryconditionsButtonGroup  matlab.ui.container.ButtonGroup
        DirichletButton                matlab.ui.control.RadioButton
        NeumannButton                  matlab.ui.control.RadioButton
        PeriodicButton                 matlab.ui.control.RadioButton
        RecordButton                   matlab.ui.control.StateButton
        ResetButton                    matlab.ui.control.Button
        PauseButton                    matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        StatusBar                      matlab.ui.control.Label
    end

    % Changes: U.P.
    % colormap jet
    % Add  property Clim set default value is [0 1] 
    % kill window instead of hideing.
    % Set colors of subwindow: black BGColor, White all axis and colorbar

    % Thing on the wishlist: 
    % Set Colormap by user
    % Some presets for coefficients
    % 


    properties (Access = private)
        rootDir
        isRunning logical
        isClosing logical = false
        plotFig   
        p
        grid 
        model
        plotAx
        plotImg
        isRecording logical
        videoWriter 
        videoFile
        videoFPS
        videoFormat
        cons_u0
        cons_v0
        p0_F
        p0_k
        cons_snap
        LogFileID = -1          % File-ID from fopen
        Colormap string = "jet" % Stores the colormap
        TEnd double = Inf;      % Max time to run
        CLim (1,2) double = [0 1];
         % Description
        UITheme = 'dark';       % Color scheme for apps UI. 
                                % Default is MATLAB's global UI scheme.
    end 
   
    properties (Access = private,...
              Constant = true)
        % Some colors 
        lampGray  = [0.6 0.6 0.6];
        lampGreen = [0 1 0];
        lampRed   = [1 0 0];        
        bgStatusbarLight = [0.8 0.8 0.8];
        bgStatusbarDark =  [0.3 0.4 0.4];

        % Path and filenames 
        ppp char = 'https://bildungsportal.sachsen.de/opal/auth/RepositoryEntry/17694195712/CourseNode/97879952218481?0';
        LogFileName char = 'GrayScottControllerLog.txt' % log for errormesages
        ManualFileName char = 'PPP_Report_Mehdi_Bakhshi_Zadeh.pdf'
    end

    % Additional (non-callback) methods    
    methods (Access = private)
        function updateStatusBar(app)
            %STATUS BAR  Show essential run information in the blue bar.
        
            % BC type (all edges identical in GUI)
            if isempty(app) || ~isvalid(app) || app.isClosing
                return
            end
            try
                bcType = upper(string(app.p.BC.left.type));
            catch
                bcType = "";
            end
        
            % Recording flag
            if app.isRecording
                recTxt = "REC";
            else
                recTxt = "IDLE";
            end
        
            % Time/step from model if available
            if ~isempty(app.model)
                nTxt = sprintf("n=%d", app.model.n);
                tTxt = sprintf("t=%.3g", app.model.t);
            else
                nTxt = "n=?";
                tTxt = "t=?";
            end
        
            % Field shown
            fieldTxt = upper(string(app.p.plotField));
        
            % Parameters
            fkTxt = sprintf("F=%.5f | k=%.5f", app.p.F, app.p.k);
        
            % --- Safety check for UI element ---
            if isempty(app.StatusBar) || ~isvalid(app.StatusBar)
                return
            end

            % Compose
            if strlength(bcType) > 0
                app.StatusBar.Text = sprintf("%s  |  %s  |  field=%s  |  BC=%s  |  %s  |  %s", ...
                    nTxt, tTxt, fieldTxt, bcType, fkTxt, recTxt);
            else
                app.StatusBar.Text = sprintf("%s  |  %s  |  field=%s  |  %s  |  %s", ...
                    nTxt, tTxt, fieldTxt, fkTxt, recTxt);
            end
        end
        
        function writeLog(app, msg)
            if app.LogFileID ~= -1
                ts = string(datetime("now","Format","yyyy-MM-dd HH:mm:ss"));
                fprintf(app.LogFileID, "%s\t%s\n", ts, msg);                
            end
        end
    
        function startRecording(app)
            % Start video recording if it is enabled and not already active.
            if isa(app.videoWriter, "VideoWriter")
                return
            end
        
            % Create the output folder if it does not exist.
            outDir = fullfile(app.rootDir, "results");
            if ~exist(outDir, "dir")
                [ok, msg] = mkdir(outDir);
                assert(ok, "Failed to create the results folder: %s", msg);
            end
        
            % Create a timestamped output file name.
            stamp = char(datetime("now", "Format", "yyyyMMdd_HHmmss"));
        
            if app.VideoFormatDropDown.Value == 1
                ext = ".avi";
                app.videoFile = fullfile(outDir, "video_" + string(stamp) + ext);
                app.videoWriter = VideoWriter(app.videoFile, "Motion JPEG AVI");
            else
                ext = ".mp4";
                app.videoFile = fullfile(outDir, "video_" + string(stamp) + ext);
                app.videoWriter = VideoWriter(app.videoFile, "MPEG-4");
            end
        
            % Apply the selected frame rate and open the writer.
            app.videoWriter.FrameRate = app.videoFPS;
            open(app.videoWriter);
        
            % Capture one initial frame if the plot window is available.
            try
                if ~isempty(app.plotFig) && isvalid(app.plotFig)
                    firstFrame = getframe(app.plotFig);
                    writeVideo(app.videoWriter, firstFrame);
                end
            catch ME
                app.writeLog(['Failed to capture the first video frame at line ', ...
                    num2str(ME.stack(1).line), ': ', ME.message]);
            end
        end
    end         
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Some magic to get the global MATLAB UI color scheme.
            uif = uifigure('Visible','off');
            app.UITheme = theme(uif); 
            close(uif);

            app.GrayScottContollerUIFigure.Tag = "GrayScottControllerUI";
            app.GrayScottContollerUIFigure.Theme = app.UITheme;
            switch app.UITheme.BaseColorStyle
                case 'light'
                    app.StatusBar.BackgroundColor = app.bgStatusbarLight;
                case 'dark'
                    app.StatusBar.BackgroundColor = app.bgStatusbarDark;
            end

            appFile = mfilename("fullpath");
            if isempty(appFile)
                appFile = mfilename("fullpath");
            end
        
            appDir  = fileparts(appFile);
            app.rootDir = fileparts(appDir);

            logDir = fullfile(app.rootDir, "results");
            if ~exist(logDir, "dir")
                [ok,msg] = mkdir(logDir);
                assert(ok, "Failed to create results directory: %s", msg);
            end
            logPath = fullfile(logDir, app.LogFileName);
            fid = fopen(logPath, "a", "n", "UTF-8"); % append, UTF-8
            if fid == -1
                uialert(app.GrayScottContollerUIFigure, "Logfile not found", "Error");
            else
                app.LogFileID = fid;             
            end
        
            addpath(genpath(fullfile(app.rootDir,"src")));
            addpath(genpath(fullfile(app.rootDir,"tests")));
        
            % --- UI / runtime flags ---
            app.isRunning   = false;
            
            app.isRecording = false;
        
            app.videoWriter = [];
            app.videoFile   = "";
            app.videoFPS    = 30;
            app.VideoFPSEditField.Value = app.videoFPS;
            app.videoFormat = string(app.VideoFormatDropDown.Value);
        
            app.PauseButton.Enable = "off";
        
            % --- Parameters (GUI policy: sparse solver fixed) ---
            app.p = defaultParams("pearson");
            app.p = finalizeParams(app.p);
            app.p.diffusionMode = "stencil";
        
            % Cache initial GUI defaults for Reset (F,k only)
            app.p0_F = app.p.F;
            app.p0_k = app.p.k;
        
            rng(app.p.seed, "twister");
        
            % --- Sync GUI widgets with parameters ---
            app.PlotFieldDropDown.Value = upper(app.p.plotField);          % 'U' or 'V'
            app.p.plotField = lower(string(app.PlotFieldDropDown.Value));  % "u"/"v"
        
            app.FSlider.Value = app.p.F;
            app.kSlider.Value = app.p.k;
        
            % GUI policy: Du, Dv visible but read-only
            app.D_uEditField.Value    = app.p.Du;
            app.D_vEditField.Value    = app.p.Dv;
            app.D_uEditField.Editable = "off";
            app.D_vEditField.Editable = "off";
        
            % --- Build model BEFORE status bar (so n,t are available) ---
            app.grid  = buildGrid(app.p);
            app.model = GrayScottModel(app.p, app.grid);
        
            app.cons_u0   = app.model.u;
            app.cons_v0   = app.model.v;
            app.cons_snap = struct();
        
            % --- Lamp (idle at startup) ---
            app.Lamp.Color = app.lampGray;
        
            % --- Create / refresh plot window ---
            if isempty(app.plotFig) || ~isvalid(app.plotFig)
                app.plotFig = figure('Name','Gray-Scott Plot',...
                    'NumberTitle','off',...
                    'MenuBar','none',...
                    'ToolBar','figure',...
                    'Resize', 'off',...
                    'Theme',app.UITheme);
            else
                figure(app.plotFig);
            end
        
            [U, V] = app.model.getFields2D();
        
            if app.p.plotField == "v"
                Z = V;
                fieldChar = "V";
            else
                Z = U;
                fieldChar = "U";
            end
        
            figure(app.plotFig);
            clf(app.plotFig);
        
            app.plotAx  = axes(app.plotFig);
            app.plotImg = imagesc(app.plotAx, Z);
        
            axis(app.plotAx, 'image');
            set(app.plotAx, 'YDir', 'normal');
            
            app.plotAx.CLim = app.CLim;
            app.plotAx.CLimMode = 'manual';
            colormap(app.plotAx, app.Colormap);

            colorbar(app.plotAx); 
            title(app.plotAx, "Initial Field " + fieldChar);
        
            % --- Status bar AFTER model is ready ---
            app.updateStatusBar();             

            % GUI policy: hide unfinished menu items (keep GUI simple)
            app.LoadmodelMenu.Visible  = "on";
            app.SavemodelMenu.Visible  = "on";
          
            app.ExitMenu.Visible       = "on";
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            
            app.isRunning = true;
        
            app.RunButton.Enable   = "off";
            app.PauseButton.Enable = "on";

            % Consistency snapshot settings (matches non-GUI reference logic)
            Nt_exact = app.p.T / app.p.dt;
            assert(abs(Nt_exact - round(Nt_exact)) < 1e-12, "T must be divisible by dt.");
            nStepsTarget = round(Nt_exact);
            kSnapTarget  = round(nStepsTarget/2);

            % Open video writer if recording is enabled
            if app.isRecording
                % Start recording before entering the simulation loop.
                try
                    app.startRecording();
                catch ME
                    app.isRecording = false;
                    app.RecordButton.Value = false;
                    app.videoWriter = [];
                    app.videoFile = "";
                    app.Lamp.Color = app.lampGray;
                    app.StatusBar.Text = "Recording could not be started.";
            
                    app.writeLog(['Recording could not be started at line ', ...
                        num2str(ME.stack(1).line), ': ', ME.message]);
                end
            end
        
            % Safety: if plot figure was closed, recreate it
            if isempty(app.plotFig) || ~isvalid(app.plotFig)
                app.plotFig = figure('Name','Gray-Scott Plot',...
                    'NumberTitle','off',...
                    'MenuBar','none',...
                    'Resize', 'off',...
                    'ToolBar','figure',...
                    'Theme',app.UITheme);

                app.plotAx  = axes(app.plotFig);

                [U, ~] = app.model.getFields2D();
                
                app.plotImg = imagesc(app.plotAx, U);
                
                axis(app.plotAx, 'image');
                set(app.plotAx, 'YDir', 'normal');
                
                app.plotAx.CLim = app.CLim;
                app.plotAx.CLimMode = 'manual';
                colormap(app.plotAx, app.Colormap);
                
                colorbar(app.plotAx);
            end
        
            % Main loop (no timer): runs until Pause or app closed
            while isvalid(app) && app.isRunning && ~app.isClosing && app.TEnd > app.model.t
        
                % Exit immediately if the app is closing.
                if ~isvalid(app) || app.isClosing
                    return
                end

                % Make sure model uses latest F,k (runtime tuning)
                app.model.p.F = app.p.F;
                app.model.p.k = app.p.k;
        
                % Step a small batch per UI refresh
                nBatch = 20;
                info = struct();
                for i = 1:nBatch
                    if ~isvalid(app) || app.isClosing || ~app.isRunning
                        break;
                    end
                
                    info = app.model.step();
                    if isfield(info,'hasNaNInf') && info.hasNaNInf
                        break;
                    end
                end

                % Capture snapshot once at kSnapTarget
                if isempty(app.cons_snap) || ~isfield(app.cons_snap, "kSnap")
                    if app.model.n >= kSnapTarget
                        app.cons_snap.us   = app.model.u;
                        app.cons_snap.vs   = app.model.v;
                        app.cons_snap.kSnap = kSnapTarget;
                        app.cons_snap.nStepsTarget = nStepsTarget;
                    end
                end
                % Plot update every plotEvery steps
                if app.p.plotEvery > 0 && mod(app.model.n, app.p.plotEvery) == 0
                
                    % Recreate plot window if it was closed during run
                    if isempty(app.plotFig) || ~isvalid(app.plotFig) || isempty(app.plotAx) || ~isvalid(app.plotAx) || ...
                       isempty(app.plotImg) || ~isvalid(app.plotImg)
                
                        % If recording was active, stop it (plot was interrupted)
                        if app.isRecording
                            app.isRecording = false;
                        
                            if isvalid(app) && isprop(app, "RecordButton") && ~isempty(app.RecordButton) && isvalid(app.RecordButton)
                                app.RecordButton.Value = false;
                            end
                        
                            vw = app.videoWriter;
                            if ~isempty(vw) && isa(vw, "VideoWriter")
                                try
                                    close(vw);
                                catch ME
                                    app.writeLog(['Failed to close the video file at line ', ...
                                        num2str(ME.stack(1).line), ': ', ME.message]);
                                end
                            end
                        
                            app.videoWriter = [];
                            app.videoFile = "";
                        end

                        % Fix the problem with non adapted subwindow (wrong
                        % color etc.) U.P.
                        app.plotFig = figure('Name','Gray-Scott Plot',...
                            'NumberTitle','off',...
                            'MenuBar','none',...
                            'Resize', 'off',...
                            'ToolBar','figure',...
                            'Theme',app.UITheme);
                        
                        app.plotAx  = axes(app.plotFig); %#ok<LAXES>
                        
                        [U0, V0] = app.model.getFields2D();
                        if app.p.plotField == "v"
                            Z0 = V0;
                        else
                            Z0 = U0;
                        end
                        
                        app.plotImg = imagesc(app.plotAx, Z0);
                        
                        axis(app.plotAx, 'image');
                        set(app.plotAx, 'YDir', 'normal');
                        
                        app.plotAx.CLim = app.CLim;
                        app.plotAx.CLimMode = 'manual';
                        colormap(app.plotAx, app.Colormap);
                        
                        colorbar(app.plotAx);
                    end
                
                    [U, V] = app.model.getFields2D();
                
                    if app.p.plotField == "v"
                        Z = V;
                        fieldChar = "V";
                    else
                        Z = U;
                        fieldChar = "U";
                    end
                
                    if ~isempty(app.plotImg) && isvalid(app.plotImg)
                        app.plotImg.CData = Z;
                        title(app.plotAx, sprintf("Field %s, F = %.4g, k = %.4g",...
                            fieldChar, app.p.F , app.p.k));
                
                        % Write video frame (race-safe: Pause/Reset/Close)
                        if app.isRecording && ~isempty(app.videoWriter)
                            vw = app.videoWriter;   % snapshot
                            if isa(vw, "VideoWriter")
                                try
                                    if ~isvalid(app) || app.isClosing || isempty(app.plotFig) || ~isvalid(app.plotFig)
                                        return
                                    end
                        
                                    frame = getframe(app.plotFig);
                                    writeVideo(vw, frame);
                                catch
                                    % Stop recording quietly if the figure or writer is no longer available.
                                    if ~isvalid(app) || app.isClosing
                                        return
                                    end
                        
                                    app.isRecording = false;
                        
                                    if isprop(app, "RecordButton") && ~isempty(app.RecordButton) && isvalid(app.RecordButton)
                                        app.RecordButton.Value = false;
                                    end
                        
                                    try
                                        close(vw);
                                    catch ME
                                        app.writeLog(['Failed to close the video file at line ', ...
                                            num2str(ME.stack(1).line), ': ', ME.message]);
                                    end
                        
                                    app.videoWriter = [];
                                    app.videoFile = "";
                                end
                            end
                        end
                    end
                end
        
                % Stop on NaN/Inf
                if isfield(info,'hasNaNInf') && info.hasNaNInf
                    app.isRunning = false;
        
                    app.Lamp.Color = app.lampRed;
                    app.StatusBar.Text = "Stopped: NaN/Inf detected.";
        
                    app.RunButton.Enable   = "on";
                    app.PauseButton.Enable = "off";

                    % Finalize recording on numerical failure
                    if app.isRecording && ~isempty(app.videoWriter) && isa(app.videoWriter, "VideoWriter")
                        app.isRecording = false;
                    
                        if isvalid(app) && isprop(app, "RecordButton") && ~isempty(app.RecordButton) && isvalid(app.RecordButton)
                            app.RecordButton.Value = false;
                        end
                    
                        try
                            close(app.videoWriter);
                        catch ME
                            app.writeLog(['Failed to close the video file at line ', ...
                                num2str(ME.stack(1).line), ': ', ME.message]);
                        end
                    
                        app.videoWriter = [];
                        app.videoFile = "";
                    end
                    break;
                end
     
                % Allow UI callbacks to run (Pause/Reset/close)
                drawnow limitrate;                 
                app.updateStatusBar(); 
                 
            end
             
            % If the app is closing, leave immediately and do not touch UI objects.
            if ~isvalid(app) || app.isClosing
                return
            end
            
            % Restore the button states after the simulation stops normally.
            if isvalid(app) && isprop(app, "RunButton") && isvalid(app.RunButton)
                app.RunButton.Enable = "on";
            end
            
            if isvalid(app) && isprop(app, "PauseButton") && isvalid(app.PauseButton)
                app.PauseButton.Enable = "off";
            end
            
            % Stop recording when the simulation ends normally.
            if app.isRecording && ~isempty(app.videoWriter) && isa(app.videoWriter, "VideoWriter")
                app.isRecording = false;
            
                if isvalid(app) && isprop(app, "RecordButton") && ~isempty(app.RecordButton) && isvalid(app.RecordButton)
                    app.RecordButton.Value = false;
                end
            
                try
                    close(app.videoWriter);
                catch ME
                    app.writeLog(['Failed to close the video file at line ', ...
                        num2str(ME.stack(1).line), ': ', ME.message]);
                end
            
                app.videoWriter = [];
                app.videoFile = "";
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.isRunning = false;
            app.RunButton.Enable = "on";
            app.PauseButton.Enable = "off";

            % Finalize recording on pause
            if app.isRecording && isa(app.videoWriter, "VideoWriter")
                app.isRecording = false;
                app.RecordButton.Value = false;
            
                try
                    close(app.videoWriter);
                catch ME
                    app.writeLog(['Error in Line ',...
                        ' ',num2str(ME.stack(1).line),'. Message was: ',ME.message]);
                end
                app.videoWriter = [];
            end
            app.Lamp.Color = app.lampGray;

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.isRunning = false;
            app.RunButton.Enable = "on";
            app.PauseButton.Enable = "off";

            % Finalize recording on reset
            if app.isRecording && isa(app.videoWriter, "VideoWriter")
                app.isRecording = false;
                app.RecordButton.Value = false;
            
                try
                    close(app.videoWriter);
                catch
                end
                app.videoWriter = [];
            end

            % Restore F and k to initial GUI defaults
            app.p.F = app.p0_F;
            app.p.k = app.p0_k;
            
            % Keep GUI widgets consistent with the restored defaults
            app.FSlider.Value = app.p.F;
            app.kSlider.Value = app.p.k;

            app.p = finalizeParams(app.p);
            rng(app.p.seed, "twister");

            app.p.diffusionMode = "stencil";

            % Recreate model using current parameters
            app.grid  = buildGrid(app.p);
            app.model = GrayScottModel(app.p, app.grid);

            app.cons_u0 = app.model.u;
            app.cons_v0 = app.model.v;
            app.cons_snap = struct();
            
            % Sync model params (in case constructor copies p)
            app.model.p.F = app.p.F;
            app.model.p.k = app.p.k;
            
            app.Lamp.Color = app.lampGray;

            % Redraw initial conidtion
            [U, V] = app.model.getFields2D();

            if app.p.plotField == "v"
                Z = V;
                fieldChar = "V";
            else
                Z = U;
                fieldChar = "U";
            end
            
            if ~isempty(app.plotImg) && isvalid(app.plotImg)
               app.plotImg.CData = Z;
               title(app.plotAx, sprintf("Initial %s (Reset) (n=%d, t=%.3g)", ...
                fieldChar, app.model.n, app.model.t));
                drawnow;
            end

            % Keep diffusion coefficients visible (GUI read-only)
            app.D_uEditField.Value = app.p.Du;
            app.D_vEditField.Value = app.p.Dv;
            app.D_uEditField.Editable = "off";
            app.D_vEditField.Editable = "off";
            
            % uncheck all presets
            app.SpotsMenu.Checked = "off";
            app.Labyrinth1Menu.Checked = "off";
            app.Labyrinth2Menu.Checked = "off";
            app.Chaos1Menu.Checked = "off";
            app.Chaos2Menu.Checked = "off";

            
            app.updateStatusBar();

        end

        % Callback function: ExitMenu, GrayScottContollerUIFigure
        function UIFigureCloseRequest(app, event)
            % Mark the app as closing and stop the simulation loop.
            app.isClosing = true;
            app.isRunning = false;
        
            % Stop recording safely if it is active.
            if app.isRecording
                app.isRecording = false;
        
                if isvalid(app) && isprop(app, "RecordButton") && isvalid(app.RecordButton)
                    app.RecordButton.Value = false;
                end
        
                vw = app.videoWriter;
                if ~isempty(vw) && isa(vw, "VideoWriter")
                    try
                        close(vw);
                    catch ME
                        app.writeLog(['Failed to close the video file at line ', ...
                            num2str(ME.stack(1).line), ': ', ME.message]);
                    end
                end
                app.videoWriter = [];
                app.videoFile = "";
            end
        
            % Close the plot window if it is open.
            try
                if ~isempty(app.plotFig) && isvalid(app.plotFig)
                    close(app.plotFig);
                end
            catch ME
                app.writeLog(['Failed to close the plot window at line ', ...
                    num2str(ME.stack(1).line), ': ', ME.message]);
            end
        
            % Close the log file before deleting the app.
            if app.LogFileID ~= -1
                fclose(app.LogFileID);
                app.LogFileID = -1;
            end
        
            try
                delete(app);
            catch ME
                warning("App could not be deleted cleanly: %s", ME.message);
            end
        end

        % Value changed function: FSlider
        function FSliderValueChanged(app, event)
            app.p.F = app.FSlider.Value;
            if ~isempty(app.model)
                app.model.p.F = app.p.F;
            end
            
            app.updateStatusBar();
            
            % deaktivale all presets
            app.SpotsMenu.Checked = "off";
            app.Labyrinth1Menu.Checked = "off";
            app.Labyrinth2Menu.Checked = "off";
            app.Chaos1Menu.Checked = "off";
            app.Chaos2Menu.Checked = "off";
            app.MitosisMenu.Checked = "off";
            app.SolitonesMenu.Checked = "off";
            app.FlowerMenu.Checked = "off";
            app.FingerMenu.Checked = "off";
            app.USkateMenu.Checked = "off";
            app.MazesMenu.Checked = "off";
            app.SpiralsMenu.Checked = "off";
        end

        % Value changed function: kSlider
        function kSliderValueChanged(app, event)
            app.p.k = app.kSlider.Value;
            if ~isempty(app.model)
                app.model.p.k = app.p.k;
            end

            app.updateStatusBar();

            % deaktivale all presets
            app.SpotsMenu.Checked = "off";
            app.Labyrinth1Menu.Checked = "off";
            app.Labyrinth2Menu.Checked = "off";
            app.Chaos1Menu.Checked = "off";
            app.Chaos2Menu.Checked = "off";
            app.MitosisMenu.Checked = "off";
            app.SolitonesMenu.Checked = "off";
            app.FlowerMenu.Checked = "off";
            app.FingerMenu.Checked = "off";
            app.USkateMenu.Checked = "off";
            app.MazesMenu.Checked = "off";
            app.SpiralsMenu.Checked = "off";

        end

        % Value changed function: PlotFieldDropDown
        function PlotFieldDropDownValueChanged(app, event)
        
            % Store selected plot field 
            app.p.plotField = lower(string(app.PlotFieldDropDown.Value)); % "u" or "v"
        
            % Keep status text consistent 
            app.updateStatusBar();

        end

        % Value changed function: RecordButton
        function RecordButtonValueChanged(app, event)
            % Enable or disable video recording based on the toggle state.
            app.isRecording = logical(app.RecordButton.Value);
        
            if app.isRecording
                % If the simulation is already running, start recording immediately.
                if app.isRunning
                    try
                        app.startRecording();
                    catch ME
                        app.isRecording = false;
                        app.RecordButton.Value = false;
                        app.videoWriter = [];
                        app.videoFile = "";
                        app.Lamp.Color = app.lampGray;
                        app.StatusBar.Text = "Recording could not be started.";
        
                        app.writeLog(['Recording could not be started at line ', ...
                            num2str(ME.stack(1).line), ': ', ME.message]);
                    end
                end
            else
                % Close the video writer if recording is being turned off.
                if ~isempty(app.videoWriter) && isa(app.videoWriter, "VideoWriter")
                    try
                        close(app.videoWriter);
                    catch ME
                        app.writeLog(['Failed to close the video file at line ', ...
                            num2str(ME.stack(1).line), ': ', ME.message]);
                    end
                end
        
                app.videoWriter = [];
                app.videoFile = "";
            end
        
            % Update the recording indicator.
            if app.isRecording
                app.Lamp.Color = app.lampGreen;
            else
                app.Lamp.Color = app.lampGray;
            end
        
            app.updateStatusBar();
        end

        % Value changed function: VideoFPSEditField
        function VideoFPSEditFieldValueChanged(app, event)
            app.videoFPS = app.VideoFPSEditField.Value;
        
            % If recording is already open, update the writer too (safe try)
            vw = app.videoWriter;
            if isa(vw, "VideoWriter")
                try
                    vw.FrameRate = app.videoFPS;
                catch ME
                    app.writeLog(['Error in Line ',...
                        ' ',num2str(ME.stack(1).line),'. Message was: ',ME.message]);
                end
            end            
        end

        % Value changed function: VideoFormatDropDown
        function VideoFormatDropDownValueChanged(app, event)
            app.videoFormat = string(app.VideoFormatDropDown.Value);   
            % MP4 together with Linux don't work. 
            if isunix &&  (event.ValueIndex == 1)
                uialert(app.GrayScottContollerUIFigure,{'MPEG-4 format is not supported for Unix/Linux.';...
                    'Use AVI Motion JPEG instead.'},"Error");
            end
        end

        % Selection changed function: BoundaryconditionsButtonGroup
        function BoundaryconditionsButtonGroupSelectionChanged(app, event)
                % Determine selected BC type (always define bcType)
                bcType = "periodic";  % default (prevents undefined-variable errors)
                selected = app.BoundaryconditionsButtonGroup.SelectedObject;
            
                if selected == app.PeriodicButton
                    bcType = "periodic";
                elseif selected == app.NeumannButton
                    bcType = "neumann";
                elseif selected == app.DirichletButton
                    bcType = "dirichlet";
                end
            
                app.p.diffusionMode = "stencil";

                % Apply chosen type to all sides (GUI policy: all edges share same BC)
                sides = ["left","right","bottom","top"];
                for s = 1:numel(sides)
                    side = sides(s);
                    app.p.BC.(side).type = bcType;
            
                    % GUI uses constant homogeneous BC values (advanced options in script mode)
                    if bcType == "dirichlet"
                        app.p.BC.(side).dirichletMode = "constant";
                        app.p.BC.(side).dirichletValue.u = 0.0;
                        app.p.BC.(side).dirichletValue.v = 0.0;
            
                    elseif bcType == "neumann"
                        app.p.BC.(side).neumannMode = "constant";
                        app.p.BC.(side).neumannValue.u = 0.0;
                        app.p.BC.(side).neumannValue.v = 0.0;
                    end
                end
            
                % Policy A: boundary-condition changes require a reset
                app.isRunning = false;
                app.RunButton.Enable   = "on";
                app.PauseButton.Enable = "off";
            
                % Finalize recording (same behavior as Reset/Pause)
                if app.isRecording && isa(app.videoWriter, "VideoWriter")
                    app.isRecording = false;
                    app.RecordButton.Value = false;
                    try
                        close(app.videoWriter);
                    catch
                    end
                    app.videoWriter = [];
                end
            
                % Rebuild model using current GUI parameters
                app.p = finalizeParams(app.p);
                rng(app.p.seed, "twister");
            
                app.grid  = buildGrid(app.p);
                app.model = GrayScottModel(app.p, app.grid);
            
                app.cons_u0 = app.model.u;
                app.cons_v0 = app.model.v;
                app.cons_snap = struct();
            
                app.Lamp.Color = app.lampGray;
            
                % Redraw initial state
                [U, V] = app.model.getFields2D();
                if app.p.plotField == "v"
                    Z = V;
                    fieldChar = "V";
                else
                    Z = U;
                    fieldChar = "U";
                end
            
                if ~isempty(app.plotImg) && isvalid(app.plotImg)
                    app.plotImg.CData = Z;
                    title(app.plotAx, sprintf("Initial %s (BC reset) (n=%d, t=%.3g)", ...
                        fieldChar, app.model.n, app.model.t));
                    drawnow;
                end

            app.updateStatusBar();
        end

        % Menu selected function: JetPearsonpaperMenu
        function JetPearsonpaperMenuSelected(app, event)
            app.Colormap = "jet";
            app.JetPearsonpaperMenu.Checked = "on";
            app.ParulaMatlabdefaultMenu.Checked = "off";
            app.CoolMenu.Checked = "off";  
            app.plotAx.Colormap =  colormap(app.Colormap);
        end

        % Menu selected function: CoolMenu
        function CoolMenuSelected(app, event)
            app.Colormap = "cool";
            app.JetPearsonpaperMenu.Checked = "off";
            app.ParulaMatlabdefaultMenu.Checked = "off";
            app.CoolMenu.Checked = "on"; 
            app.plotAx.Colormap =  colormap(app.Colormap);
        end

        % Menu selected function: ParulaMatlabdefaultMenu
        function ParulaMatlabdefaultMenuSelected(app, event)
            app.Colormap = "parula";
            app.JetPearsonpaperMenu.Checked = "off";
            app.ParulaMatlabdefaultMenu.Checked = "on";
            app.CoolMenu.Checked = "off";   
            app.plotAx.Colormap =  colormap(app.Colormap);
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            %Same as reset,. but leavs F and k as they are.
            app.isRunning = false;
            app.RunButton.Enable = "on";
            app.PauseButton.Enable = "off";

            % Finalize recording on reset
            if app.isRecording && isa(app.videoWriter, "VideoWriter")
                app.isRecording = false;
                app.RecordButton.Value = false;
            
                try
                    close(app.videoWriter);
                catch
                end
                app.videoWriter = [];
            end


            app.p = finalizeParams(app.p);
            rng(app.p.seed, "twister");

            app.p.diffusionMode = "stencil";

            % Recreate model using current parameters
            app.grid  = buildGrid(app.p);
            app.model = GrayScottModel(app.p, app.grid);

            app.cons_u0 = app.model.u;
            app.cons_v0 = app.model.v;
            app.cons_snap = struct();
            
            % Sync model params (in case constructor copies p)
             
            
            app.Lamp.Color = app.lampGray;

            % Redraw initial conidtion
            [U, V] = app.model.getFields2D();

            if app.p.plotField == "v"
                Z = V;
                fieldChar = "V";
            else
                Z = U;
                fieldChar = "U";
            end
            
            if ~isempty(app.plotImg) && isvalid(app.plotImg)
                app.plotImg.CData = Z;
                title(app.plotAx, sprintf("Initial %s (Restart) (n=%d, t=%.3g)", ...
                    fieldChar, app.model.n, app.model.t));
                drawnow;
            end

            % Keep diffusion coefficients visible (GUI read-only)
            app.D_uEditField.Value = app.p.Du;
            app.D_vEditField.Value = app.p.Dv;
            app.D_uEditField.Editable = "off";
            app.D_vEditField.Editable = "off";
            
            % uncheck all presets


            app.updateStatusBar();

        end

        % Menu selected function: SavemodelMenu
        function SavemodelMenuSelected(app, event)
            % Create variable and save it
              
            model = app.model;  %#ok<NASGU,ADPROPLC>              
            uisave({'model'},'model.mat');

        end

        % Menu selected function: LoadmodelMenu
        function LoadmodelMenuSelected(app, event)
            %Opens a model
            uiopen("load");
            if exist('model','var') && isa(model, 'GrayScottModel') 
                app.model = model; %#ok<ADPROPLC>
            else 
                uialert(app.GrayScottContollerUIFigure,{'Not a valid model.';...
                    'Make sure that the file contains a valid Gray-Scott Model'},'Error');
                return
            end
            app.p = app.model.p;
            app.FSlider.Value = app.p.F;
            app.kSlider.Value = app.p.k;
            app.updateStatusBar();
        end

        % Menu selected function: AbouttheAppMenu
        function AbouttheAppMenuSelected(app, event)
            uialert(app.GrayScottContollerUIFigure,{'Gray-Scott Simulator.',...
                'A tool to study Turing instabilities in the Gray-Scott-Model.',...
                'This version is a fork from the PPP of Mehdi Bakhshi Zadeh',...
                '(C) 2026 under GPL.'},'About','icon','info');
        end

        % Menu selected function: AboutPPPMenu
        function AboutPPPMenuSelected(app, event)
            web(app.ppp);
        end

        % Menu selected function: InfdefaultMenu
        function InfdefaultMenuSelected(app, event)
            app.TEnd = Inf;
            app.InfdefaultMenu.Checked = "on";
            app.Menu20000.Checked = "off";
            app.Menu50000.Checked = "off";
            app.Menu100000.Checked = "off";
        end

        % Menu selected function: Menu20000
        function Menu20000Selected(app, event)
            app.TEnd = 20000;
            app.InfdefaultMenu.Checked = "off";
            app.Menu20000.Checked = "on";
            app.Menu50000.Checked = "off";
            app.Menu100000.Checked = "off";
        end

        % Menu selected function: Menu50000
        function Menu50000Selected(app, event)
            app.TEnd = 50000;
            app.InfdefaultMenu.Checked = "off";
            app.Menu20000.Checked = "off";
            app.Menu50000.Checked = "on";
            app.Menu100000.Checked = "off";
        end

        % Menu selected function: Menu100000
        function Menu100000Selected(app, event)
            app.TEnd = 100000;
            app.InfdefaultMenu.Checked = "off";
            app.Menu20000.Checked = "off";
            app.Menu50000.Checked = "off";
            app.Menu100000.Checked = "on";
        end

        % Menu selected function: MATLABdefaultMenu
        function MATLABdefaultMenuSelected(app, event)
            % Check actual global color scheme
            uif = uifigure('Visible','off');
            app.UITheme = theme(uif); 
            close(uif);

            app.MATLABdefaultMenu.Checked = 'on';
            app.DarkMenu.Checked = 'off';
            app.LightMenu.Checked = 'off';

            % Set color scheme
            app.GrayScottContollerUIFigure.Theme = app.UITheme;
            if isvalid(app.plotFig)
                app.plotFig.Theme = app.UITheme;
            end
            % Change color of status bar
            switch app.UITheme.BaseColorStyle
                case 'light'
                    app.StatusBar.BackgroundColor =  app.bgStatusbarLight;
                case 'dark'
                    app.StatusBar.BackgroundColor = app.bgStatusbarDark;
            end
        end

        % Menu selected function: DarkMenu
        function DarkMenuSelected(app, event)
           
            app.UITheme = 'Dark';
            % here check if valid!
             
            app.MATLABdefaultMenu.Checked = 'off';
            app.DarkMenu.Checked = 'on';
            app.LightMenu.Checked = 'off';           

            % Set color scheme
            app.GrayScottContollerUIFigure.Theme = app.UITheme;
            if isvalid(app.plotFig)
                app.plotFig.Theme = app.UITheme;
            end
            app.StatusBar.BackgroundColor = app.bgStatusbarDark;
        end

        % Menu selected function: LightMenu
        function LightMenuSelected(app, event)
            app.UITheme = 'Light';           
             
            app.MATLABdefaultMenu.Checked = 'off';
            app.DarkMenu.Checked = 'off';
            app.LightMenu.Checked = 'on';

            % Set color scheme
            app.GrayScottContollerUIFigure.Theme = app.UITheme;
            if isvalid(app.plotFig)
                app.plotFig.Theme = app.UITheme;
            end
            app.StatusBar.BackgroundColor = app.bgStatusbarLight;
        end

        % Menu selected function: Labyrinth1Menu
        function Labyrinth1MenuSelected(app, event)
            app.p.F = 0.05;
            app.p.k = 0.066;
            app.FSlider.Value = 0.05;
            app.kSlider.Value = 0.066;
            app.SpotsMenu.Checked = "off";
            app.Labyrinth1Menu.Checked = "on";
            app.Labyrinth2Menu.Checked = "off";
            app.Chaos1Menu.Checked = "off";
            app.Chaos2Menu.Checked = "off";
            app.MitosisMenu.Checked = "off";
            app.SolitonesMenu.Checked = "off";
            app.FlowerMenu.Checked = "off";
            app.FingerMenu.Checked = "off";
            app.USkateMenu.Checked = "off";
            app.MazesMenu.Checked = "off";
            app.SpiralsMenu.Checked = "off";
            app.DefaultMenu.Checked = "off";
        end

        % Menu selected function: Labyrinth2Menu
        function Labyrinth2MenuSelected(app, event)
           app.p.F = 0.05;
            app.p.k = 0.064;
            app.FSlider.Value = 0.05;
            app.kSlider.Value = 0.064;
            app.SpotsMenu.Checked = "off";
            app.Labyrinth1Menu.Checked = "off";
            app.Labyrinth2Menu.Checked = "on";
            app.Chaos1Menu.Checked = "off";
            app.Chaos2Menu.Checked = "off";
            app.MitosisMenu.Checked = "off";
            app.SolitonesMenu.Checked = "off";
            app.FlowerMenu.Checked = "off";
            app.FingerMenu.Checked = "off";
            app.USkateMenu.Checked = "off";
            app.MazesMenu.Checked = "off";
            app.SpiralsMenu.Checked = "off";
            app.DefaultMenu.Checked = "off";
        end

        % Menu selected function: Chaos1Menu
        function Chaos1MenuSelected(app, event)
            app.p.F = 0.018;
            app.p.k = 0.05;
            app.FSlider.Value = 0.018;
            app.kSlider.Value = 0.05;
            app.SpotsMenu.Checked = "off";
            app.Labyrinth1Menu.Checked = "off";
            app.Labyrinth2Menu.Checked = "off";
            app.Chaos1Menu.Checked = "on";
            app.Chaos2Menu.Checked = "off";
            app.MitosisMenu.Checked = "off";
            app.SolitonesMenu.Checked = "off";
            app.FlowerMenu.Checked = "off";
            app.FingerMenu.Checked = "off";
            app.USkateMenu.Checked = "off";
            app.MazesMenu.Checked = "off";
            app.SpiralsMenu.Checked = "off";
            app.DefaultMenu.Checked = "off";
        end

        % Menu selected function: Chaos2Menu
        function Chaos2MenuSelected(app, event)
            app.p.F = 0.020;
            app.p.k = 0.05;
            app.FSlider.Value = 0.020;
            app.kSlider.Value = 0.05;
            app.SpotsMenu.Checked = "off";
            app.Labyrinth1Menu.Checked = "off";
            app.Labyrinth2Menu.Checked = "off";
            app.Chaos1Menu.Checked = "off";
            app.Chaos2Menu.Checked = "on";
            app.MitosisMenu.Checked = "off";
            app.SolitonesMenu.Checked = "off";
            app.FlowerMenu.Checked = "off";
            app.FingerMenu.Checked = "off";
            app.USkateMenu.Checked = "off";
            app.MazesMenu.Checked = "off";
            app.SpiralsMenu.Checked = "off";
            app.DefaultMenu.Checked = "off";
        end

        % Menu selected function: SpotsMenu
        function SpotsMenuSelected(app, event)
            app.p.F = 0.04;
            app.p.k = 0.064;
            app.FSlider.Value = 0.04;
            app.kSlider.Value = 0.064;
            app.SpotsMenu.Checked = "on";
            app.Labyrinth1Menu.Checked = "off";
            app.Labyrinth2Menu.Checked = "off";
            app.Chaos1Menu.Checked = "off";
            app.Chaos2Menu.Checked = "off";
            app.MitosisMenu.Checked = "off";
            app.SolitonesMenu.Checked = "off";
            app.FlowerMenu.Checked = "off";
            app.FingerMenu.Checked = "off";
            app.USkateMenu.Checked = "off";
            app.MazesMenu.Checked = "off";
            app.SpiralsMenu.Checked = "off";
            app.DefaultMenu.Checked = "off";
        end

        % Menu selected function: MitosisMenu
        function MitosisMenuSelected(app, event)
            app.p.F = 0.028;
            app.p.k = 0.062;
            app.FSlider.Value = 0.028;
            app.kSlider.Value = 0.062;
            app.SpotsMenu.Checked = "off";
            app.Labyrinth1Menu.Checked = "off";
            app.Labyrinth2Menu.Checked = "off";
            app.Chaos1Menu.Checked = "off";
            app.Chaos2Menu.Checked = "off";
            app.MitosisMenu.Checked = "on";
            app.SolitonesMenu.Checked = "off";
            app.FlowerMenu.Checked = "off";
            app.FingerMenu.Checked = "off";
            app.USkateMenu.Checked = "off";
            app.MazesMenu.Checked = "off";
            app.SpiralsMenu.Checked = "off";
            app.DefaultMenu.Checked = "off";
        end

        % Menu selected function: SolitonesMenu
        function SolitonesMenuSelected(app, event)
            app.p.F = 0.030;
            app.p.k = 0.060;
            app.FSlider.Value = 0.030;
            app.kSlider.Value = 0.060;
            % Default: all set to "off"
            app.SpotsMenu.Checked = "off";
            app.Labyrinth1Menu.Checked = "off";
            app.Labyrinth2Menu.Checked = "off";
            app.Chaos1Menu.Checked = "off";
            app.Chaos2Menu.Checked = "off";
            app.MitosisMenu.Checked = "off";
            app.SolitonesMenu.Checked = "on";
            app.FlowerMenu.Checked = "off";
            app.FingerMenu.Checked = "off";
            app.USkateMenu.Checked = "off";
            app.MazesMenu.Checked = "off";
            app.SpiralsMenu.Checked = "off";
            app.DefaultMenu.Checked = "off";
        end

        % Menu selected function: FlowerMenu
        function FlowerMenuSelected(app, event)
            app.p.F = 0.0550;
            app.p.k = 0.062;
            app.FSlider.Value = 0.0550;
            app.kSlider.Value = 0.062;
            % Default: all set to "off"
            app.SpotsMenu.Checked = "off";
            app.Labyrinth1Menu.Checked = "off";
            app.Labyrinth2Menu.Checked = "off";
            app.Chaos1Menu.Checked = "off";
            app.Chaos2Menu.Checked = "off";
            app.MitosisMenu.Checked = "off";
            app.SolitonesMenu.Checked = "off";
            app.FlowerMenu.Checked = "on";
            app.FingerMenu.Checked = "off";
            app.USkateMenu.Checked = "off";
            app.MazesMenu.Checked = "off";
            app.SpiralsMenu.Checked = "off";
            app.DefaultMenu.Checked = "off";
        end

        % Menu selected function: FingerMenu
        function FingerMenuSelected(app, event)
            app.p.F = 0.037;
            app.p.k = 0.060;
            app.FSlider.Value = 0.0370;
            app.kSlider.Value = 0.060;
            % Default: all set to "off"
            app.SpotsMenu.Checked = "off";
            app.Labyrinth1Menu.Checked = "off";
            app.Labyrinth2Menu.Checked = "off";
            app.Chaos1Menu.Checked = "off";
            app.Chaos2Menu.Checked = "off";
            app.MitosisMenu.Checked = "off";
            app.SolitonesMenu.Checked = "off";
            app.FlowerMenu.Checked = "off";
            app.FingerMenu.Checked = "on";
            app.USkateMenu.Checked = "off";
            app.MazesMenu.Checked = "off";
            app.SpiralsMenu.Checked = "off";
            app.DefaultMenu.Checked = "off";
        end

        % Menu selected function: USkateMenu
        function USkateMenuSelected(app, event)
            app.p.F = 0.062;
            app.p.k = 0.061;
            app.FSlider.Value = 0.062;
            app.kSlider.Value = 0.061;
            % Default: all set to "off"
            app.SpotsMenu.Checked = "off";
            app.Labyrinth1Menu.Checked = "off";
            app.Labyrinth2Menu.Checked = "off";
            app.Chaos1Menu.Checked = "off";
            app.Chaos2Menu.Checked = "off";
            app.MitosisMenu.Checked = "off";
            app.SolitonesMenu.Checked = "off";
            app.FlowerMenu.Checked = "off";
            app.FingerMenu.Checked = "off";
            app.USkateMenu.Checked = "on";
            app.MazesMenu.Checked = "off";
            app.SpiralsMenu.Checked = "off";
            app.DefaultMenu.Checked = "off";
        end

        % Menu selected function: MazesMenu
        function MazesMenuSelected(app, event)
            app.p.F = 0.029;
            app.p.k = 0.057;
            app.FSlider.Value = 0.029;
            app.kSlider.Value = 0.057;
            % Default: all set to "off"
            app.SpotsMenu.Checked = "off";
            app.Labyrinth1Menu.Checked = "off";
            app.Labyrinth2Menu.Checked = "off";
            app.Chaos1Menu.Checked = "off";
            app.Chaos2Menu.Checked = "off";
            app.MitosisMenu.Checked = "off";
            app.SolitonesMenu.Checked = "off";
            app.FlowerMenu.Checked = "off";
            app.FingerMenu.Checked = "off";
            app.USkateMenu.Checked = "off";
            app.MazesMenu.Checked = "on";
            app.SpiralsMenu.Checked = "off";
            app.DefaultMenu.Checked = "off";
        end

        % Menu selected function: SpiralsMenu
        function SpiralsMenuSelected(app, event)
            app.p.F = 0.014;
            app.p.k = 0.047;
            app.FSlider.Value = 0.014;
            app.kSlider.Value = 0.047;
            % Default: all set to "off"
            app.SpotsMenu.Checked = "off";
            app.Labyrinth1Menu.Checked = "off";
            app.Labyrinth2Menu.Checked = "off";
            app.Chaos1Menu.Checked = "off";
            app.Chaos2Menu.Checked = "off";
            app.MitosisMenu.Checked = "off";
            app.SolitonesMenu.Checked = "off";
            app.FlowerMenu.Checked = "off";
            app.FingerMenu.Checked = "off";
            app.USkateMenu.Checked = "off";
            app.MazesMenu.Checked = "off";
            app.SpiralsMenu.Checked = "on";
            app.DefaultMenu.Checked = "off";
        end

        % Menu selected function: DefaultMenu
        function DefaultMenuSelected(app, event)
            app.p.F = 0.030;
            app.p.k = 0.060;
            app.FSlider.Value = 0.030;
            app.kSlider.Value = 0.060;
            % Default: all set to "off"
            app.SpotsMenu.Checked = "off";
            app.Labyrinth1Menu.Checked = "off";
            app.Labyrinth2Menu.Checked = "off";
            app.Chaos1Menu.Checked = "off";
            app.Chaos2Menu.Checked = "off";
            app.MitosisMenu.Checked = "off";
            app.SolitonesMenu.Checked = "off";
            app.FlowerMenu.Checked = "off";
            app.FingerMenu.Checked = "off";
            app.USkateMenu.Checked = "off";
            app.MazesMenu.Checked = "off";
            app.SpiralsMenu.Checked = "off";
            app.DefaultMenu.Checked = "on";
        end

        % Menu selected function: ManualMenu
        function ManualMenuSelected(app, event)
                appFile = which(class(app));
                appDir  = fileparts(appFile);
            
                % Repository root: scripts -> project root
                repoRoot = fileparts(appDir);
            
                % Full path to PDF
                manualPath = fullfile(repoRoot, 'report', app.ManualFileName);
            
                if ~isfile(manualPath)
                    uialert(app.GrayScottContollerUIFigure, ...
                        {'Manual PDF not found.', ...
                         ['Expected file: ' manualPath]}, ...
                        'File not found', 'Icon', 'warning');
                    return
                end
            
                try
                    web(['file:///' strrep(manualPath, '\', '/')], '-browser');
                catch ME
                    uialert(app.GrayScottContollerUIFigure, ...
                        {'Could not open the manual PDF.', ME.message}, ...
                        'Open failed', 'Icon', 'error');
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GrayScottContollerUIFigure = uifigure('Visible', 'off');
            app.GrayScottContollerUIFigure.Name = 'Gray-Scott Contoller';
            app.GrayScottContollerUIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.GrayScottContollerUIFigure);
            app.FileMenu.Tooltip = {'Load, save model and exit.'};
            app.FileMenu.Text = 'File';
            app.LoadmodelMenu = uimenu(app.FileMenu);
            app.LoadmodelMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadmodelMenuSelected, true);
            app.LoadmodelMenu.Text = 'Load model';
            app.SavemodelMenu = uimenu(app.FileMenu);
            app.SavemodelMenu.MenuSelectedFcn = createCallbackFcn(app, @SavemodelMenuSelected, true);
            app.SavemodelMenu.Separator = 'on';
            app.SavemodelMenu.Text = 'Save model';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.ModelSettingsMenu = uimenu(app.GrayScottContollerUIFigure);
            app.ModelSettingsMenu.Tooltip = {'Settings: Colormaps, presets for F and k and time to run the model.'};
            app.ModelSettingsMenu.Text = 'Model Settings';
            app.ColormapsMenu = uimenu(app.ModelSettingsMenu);
            app.ColormapsMenu.Separator = 'on';
            app.ColormapsMenu.Text = 'Colormaps';
            app.JetPearsonpaperMenu = uimenu(app.ColormapsMenu);
            app.JetPearsonpaperMenu.MenuSelectedFcn = createCallbackFcn(app, @JetPearsonpaperMenuSelected, true);
            app.JetPearsonpaperMenu.Checked = 'on';
            app.JetPearsonpaperMenu.Text = 'Jet (Pearson paper)';
            app.ParulaMatlabdefaultMenu = uimenu(app.ColormapsMenu);
            app.ParulaMatlabdefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @ParulaMatlabdefaultMenuSelected, true);
            app.ParulaMatlabdefaultMenu.Text = 'Parula (Matlab default)';
            app.CoolMenu = uimenu(app.ColormapsMenu);
            app.CoolMenu.MenuSelectedFcn = createCallbackFcn(app, @CoolMenuSelected, true);
            app.CoolMenu.Text = 'Cool';
            app.PresetsMenu = uimenu(app.ModelSettingsMenu);
            app.PresetsMenu.Separator = 'on';
            app.PresetsMenu.Text = 'Presets';
            app.Labyrinth1Menu = uimenu(app.PresetsMenu);
            app.Labyrinth1Menu.MenuSelectedFcn = createCallbackFcn(app, @Labyrinth1MenuSelected, true);
            app.Labyrinth1Menu.Text = 'Labyrinth 1';
            app.Labyrinth2Menu = uimenu(app.PresetsMenu);
            app.Labyrinth2Menu.MenuSelectedFcn = createCallbackFcn(app, @Labyrinth2MenuSelected, true);
            app.Labyrinth2Menu.Text = 'Labyrinth 2';
            app.Chaos1Menu = uimenu(app.PresetsMenu);
            app.Chaos1Menu.MenuSelectedFcn = createCallbackFcn(app, @Chaos1MenuSelected, true);
            app.Chaos1Menu.Text = 'Chaos 1';
            app.Chaos2Menu = uimenu(app.PresetsMenu);
            app.Chaos2Menu.MenuSelectedFcn = createCallbackFcn(app, @Chaos2MenuSelected, true);
            app.Chaos2Menu.Text = 'Chaos 2';
            app.SpotsMenu = uimenu(app.PresetsMenu);
            app.SpotsMenu.MenuSelectedFcn = createCallbackFcn(app, @SpotsMenuSelected, true);
            app.SpotsMenu.Text = 'Spots';
            app.MitosisMenu = uimenu(app.PresetsMenu);
            app.MitosisMenu.MenuSelectedFcn = createCallbackFcn(app, @MitosisMenuSelected, true);
            app.MitosisMenu.Text = 'Mitosis';
            app.SolitonesMenu = uimenu(app.PresetsMenu);
            app.SolitonesMenu.MenuSelectedFcn = createCallbackFcn(app, @SolitonesMenuSelected, true);
            app.SolitonesMenu.Text = 'Solitones';
            app.FlowerMenu = uimenu(app.PresetsMenu);
            app.FlowerMenu.MenuSelectedFcn = createCallbackFcn(app, @FlowerMenuSelected, true);
            app.FlowerMenu.Text = 'Flower';
            app.FingerMenu = uimenu(app.PresetsMenu);
            app.FingerMenu.MenuSelectedFcn = createCallbackFcn(app, @FingerMenuSelected, true);
            app.FingerMenu.Text = 'Finger';
            app.USkateMenu = uimenu(app.PresetsMenu);
            app.USkateMenu.MenuSelectedFcn = createCallbackFcn(app, @USkateMenuSelected, true);
            app.USkateMenu.Text = 'U-Skate';
            app.MazesMenu = uimenu(app.PresetsMenu);
            app.MazesMenu.MenuSelectedFcn = createCallbackFcn(app, @MazesMenuSelected, true);
            app.MazesMenu.Text = 'Mazes';
            app.SpiralsMenu = uimenu(app.PresetsMenu);
            app.SpiralsMenu.MenuSelectedFcn = createCallbackFcn(app, @SpiralsMenuSelected, true);
            app.SpiralsMenu.Text = 'Spirals';
            app.DefaultMenu = uimenu(app.PresetsMenu);
            app.DefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @DefaultMenuSelected, true);
            app.DefaultMenu.Text = 'Default';
            app.TimetorunMenu = uimenu(app.ModelSettingsMenu);
            app.TimetorunMenu.Separator = 'on';
            app.TimetorunMenu.Text = 'Time to run';
            app.InfdefaultMenu = uimenu(app.TimetorunMenu);
            app.InfdefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @InfdefaultMenuSelected, true);
            app.InfdefaultMenu.Checked = 'on';
            app.InfdefaultMenu.Text = 'Inf (default)';
            app.Menu20000 = uimenu(app.TimetorunMenu);
            app.Menu20000.MenuSelectedFcn = createCallbackFcn(app, @Menu20000Selected, true);
            app.Menu20000.Text = 'Tend = 20,000';
            app.Menu50000 = uimenu(app.TimetorunMenu);
            app.Menu50000.MenuSelectedFcn = createCallbackFcn(app, @Menu50000Selected, true);
            app.Menu50000.Text = 'Tend = 50,000';
            app.Menu100000 = uimenu(app.TimetorunMenu);
            app.Menu100000.MenuSelectedFcn = createCallbackFcn(app, @Menu100000Selected, true);
            app.Menu100000.Text = 'Tend = 100,000';
            app.AppSettingsMenu = uimenu(app.GrayScottContollerUIFigure);
            app.AppSettingsMenu.Text = 'App Settings';
            app.ColorschemeMenu = uimenu(app.AppSettingsMenu);
            app.ColorschemeMenu.Text = 'Color scheme';
            app.MATLABdefaultMenu = uimenu(app.ColorschemeMenu);
            app.MATLABdefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @MATLABdefaultMenuSelected, true);
            app.MATLABdefaultMenu.Checked = 'on';
            app.MATLABdefaultMenu.Text = 'MATLAB (default)';
            app.DarkMenu = uimenu(app.ColorschemeMenu);
            app.DarkMenu.MenuSelectedFcn = createCallbackFcn(app, @DarkMenuSelected, true);
            app.DarkMenu.Text = 'Dark';
            app.LightMenu = uimenu(app.ColorschemeMenu);
            app.LightMenu.MenuSelectedFcn = createCallbackFcn(app, @LightMenuSelected, true);
            app.LightMenu.Text = 'Light';
            app.HelpMenu = uimenu(app.GrayScottContollerUIFigure);
            app.HelpMenu.Tooltip = {'Getting help.'};
            app.HelpMenu.Text = 'Help';
            app.ManualMenu = uimenu(app.HelpMenu);
            app.ManualMenu.MenuSelectedFcn = createCallbackFcn(app, @ManualMenuSelected, true);
            app.ManualMenu.Separator = 'on';
            app.ManualMenu.Text = 'Manual';
            app.AboutPPPMenu = uimenu(app.HelpMenu);
            app.AboutPPPMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutPPPMenuSelected, true);
            app.AboutPPPMenu.Separator = 'on';
            app.AboutPPPMenu.Text = 'About PPP';
            app.AbouttheAppMenu = uimenu(app.HelpMenu);
            app.AbouttheAppMenu.MenuSelectedFcn = createCallbackFcn(app, @AbouttheAppMenuSelected, true);
            app.AbouttheAppMenu.Separator = 'on';
            app.AbouttheAppMenu.Text = 'About the App';
            app.StatusBar = uilabel(app.GrayScottContollerUIFigure);
            app.StatusBar.Tooltip = {'Info bar. Run time, number of iterations, coefficients, status of the app.'};
            app.StatusBar.Text = '';
            app.Controller10Panel = uipanel(app.GrayScottContollerUIFigure);
            app.Controller10Panel.Title = 'Controller 1.0';
            app.RunButton = uibutton(app.Controller10Panel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Start the time integration. When recording is active, starts the recording.'};
            app.RunButton.Text = 'Run';
            app.PauseButton = uibutton(app.Controller10Panel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Tooltip = {'Pauses the time integration, stops recording'};
            app.PauseButton.Text = 'Pause';
            app.ResetButton = uibutton(app.Controller10Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Sets all parameters to the default ones.'};
            app.ResetButton.Text = 'Reset';
            app.RecordButton = uibutton(app.Controller10Panel, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.RecordButton.Tooltip = {'Records a movie of the solution. Recording starts when Run is clicked, ends when Pause or Record is clicked again.'};
            app.RecordButton.Text = 'Record';
            app.BoundaryconditionsButtonGroup = uibuttongroup(app.Controller10Panel);
            app.BoundaryconditionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BoundaryconditionsButtonGroupSelectionChanged, true);
            app.BoundaryconditionsButtonGroup.Tooltip = {'Chose boundary conditions. Resets the model.'};
            app.BoundaryconditionsButtonGroup.Title = 'Boundary conditions';
            app.PeriodicButton = uiradiobutton(app.BoundaryconditionsButtonGroup);
            app.PeriodicButton.Text = 'Periodic';
            app.PeriodicButton.Value = true;
            app.NeumannButton = uiradiobutton(app.BoundaryconditionsButtonGroup);
            app.NeumannButton.Text = 'Neumann';
            app.DirichletButton = uiradiobutton(app.BoundaryconditionsButtonGroup);
            app.DirichletButton.Text = 'Dirichlet';
            app.Lamp = uilamp(app.Controller10Panel);
            app.FieldLabel = uilabel(app.Controller10Panel);
            app.FieldLabel.Text = 'Field to plot';
            app.PlotFieldDropDown = uidropdown(app.Controller10Panel);
            app.PlotFieldDropDown.Items = {'U', 'V'};
            app.PlotFieldDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotFieldDropDownValueChanged, true);
            app.PlotFieldDropDown.Tooltip = {'Chose solution component  to be shown.'};
            app.PlotFieldDropDown.Value = 'U';
            app.VideoFPSEditFieldLabel = uilabel(app.Controller10Panel);
            app.VideoFPSEditFieldLabel.Text = 'Video FPS';
            app.VideoFPSEditField = uieditfield(app.Controller10Panel, 'numeric');
            app.VideoFPSEditField.Limits = [1 60];
            app.VideoFPSEditField.ValueChangedFcn = createCallbackFcn(app, @VideoFPSEditFieldValueChanged, true);
            app.VideoFPSEditField.Tooltip = {'Set frame per second for the video.'};
            app.VideoFPSEditField.Value = 30;
            app.VideoformatDropDownLabel = uilabel(app.Controller10Panel);
            app.VideoformatDropDownLabel.Text = 'Video format';
            app.VideoFormatDropDown = uidropdown(app.Controller10Panel);
            app.VideoFormatDropDown.Items = {'MP4 H.264', 'AVI Motion JPEG (default)'};
            app.VideoFormatDropDown.ItemsData = [0 1];
            app.VideoFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @VideoFormatDropDownValueChanged, true);
            app.VideoFormatDropDown.Tooltip = {'Chose video format. MP4 is not available in UNIX/LINUX.'};
            app.VideoFormatDropDown.Value = 1;
            app.EquationsPanel = uipanel(app.Controller10Panel);
            app.EquationsPanel.Tooltip = {'Shows the equations to be solved.'};
            app.EquationsPanel.Title = 'Equations';
            app.dotUD_unabla2UUV2F1ULabel = uilabel(app.EquationsPanel);
            app.dotUD_unabla2UUV2F1ULabel.Interpreter = 'latex';
            app.dotUD_unabla2UUV2F1ULabel.Text = '$\dot U = D_u \nabla ^2 U - UV^2  + F(1-U)$';
            app.dotVD_vnabla2VUV2FkVLabel = uilabel(app.EquationsPanel);
            app.dotVD_vnabla2VUV2FkVLabel.Interpreter = 'latex';
            app.dotVD_vnabla2VUV2FkVLabel.Text = '$\dot V = D_v \nabla ^2 V + UV^2  - (F+k)V$';
            app.ParametersPanel = uipanel(app.Controller10Panel);
            app.ParametersPanel.Tooltip = {'Chose parameters F and k. Works also when time integrator is running.'};
            app.ParametersPanel.Title = 'Parameters';
            app.kSliderLabel = uilabel(app.ParametersPanel);
            app.kSliderLabel.Text = 'k';
            app.kSlider = uislider(app.ParametersPanel);
            app.kSlider.Limits = [0 0.1];
            app.kSlider.MajorTicks = [0 0.02 0.04 0.06 0.08 0.1];
            app.kSlider.ValueChangedFcn = createCallbackFcn(app, @kSliderValueChanged, true);
            app.kSlider.MinorTicks = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1];
            app.kSlider.Tooltip = {'Chose k'};
            app.FSliderLabel = uilabel(app.ParametersPanel);
            app.FSliderLabel.Text = 'F';
            app.FSlider = uislider(app.ParametersPanel);
            app.FSlider.Limits = [0 0.1];
            app.FSlider.MajorTicks = [0 0.02 0.04 0.06 0.08 0.1];
            app.FSlider.ValueChangedFcn = createCallbackFcn(app, @FSliderValueChanged, true);
            app.FSlider.MinorTicks = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1];
            app.FSlider.Tooltip = {'Chose F.'};
            app.D_uLabel = uilabel(app.ParametersPanel);
            app.D_uLabel.Interpreter = 'latex';
            app.D_uLabel.Text = '$D_u$';
            app.D_uEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.D_uEditField.Tooltip = {'Shows diffusity for component U. '};
            app.D_uEditField.Value = 2e-05;
            app.D_vLabel = uilabel(app.ParametersPanel);
            app.D_vLabel.Interpreter = 'latex';
            app.D_vLabel.Text = '$D_v$';
            app.D_vEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.D_vEditField.Tooltip = {'Shows diffusity for component V. '};
            app.D_vEditField.Value = 1e-05;
            app.RestartButton = uibutton(app.Controller10Panel, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Tooltip = {'Restarts the model, parameter remain the same.'};
            app.RestartButton.Text = 'Restart';
            % Show the figure after all components are created
            app.GrayScottContollerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GrayScottController

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GrayScottContollerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GrayScottContollerUIFigure)
        end
    end
end